classdef K_Vision_System < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SNRLabel                       matlab.ui.control.Label
        HesaplaButton                  matlab.ui.control.Button
        BEditField                     matlab.ui.control.NumericEditField
        BHzEditFieldLabel              matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        RmEditFieldLabel               matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        LdBEditFieldLabel              matlab.ui.control.Label
        FdBEditField                   matlab.ui.control.NumericEditField
        FdBEditFieldLabel              matlab.ui.control.Label
        FEditField                     matlab.ui.control.NumericEditField
        fHzEditFieldLabel              matlab.ui.control.Label
        GEditField                     matlab.ui.control.NumericEditField
        GdBLabel                       matlab.ui.control.Label
        TEditField                     matlab.ui.control.NumericEditField
        TKEditFieldLabel               matlab.ui.control.Label
        PtEditField                    matlab.ui.control.NumericEditField
        PtWLabel                       matlab.ui.control.Label
        KVisionRadarSNRHesaplaycLabel  matlab.ui.control.Label
        SNRPlotAxes                    matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
         % Kullanıcı girişlerini al
            Pt = app.PtEditField.Value;
            Gt_dB = app.GEditField.Value;
            Gr_dB = Gt_dB; % aynı anten varsayımı
            f = app.FEditField.Value;
            sigma = app.SigmaEditField.Value;
            B = app.BEditField.Value;
            T = app.TEditField.Value;
            F_dB = app.FdBEditField.Value;
            L_dB = app.LEditField.Value;

% Önce tek SNR hesapla (mevcut radar_snr.m fonksiyonunu kullanarak)
            [~, snr_dB_single] = radar_snr(Pt, Gt_dB, Gr_dB, f, app.REditField.Value, sigma, L_dB, B, T, F_dB);
            app.SNRLabel.Text = sprintf('SNR: %.2f dB', snr_dB_single);

% grafiği çiz 
            axes(app.SNRPlotAxes); % App Designer içindeki eksene geç
            plot_snr(Pt, Gt_dB, Gr_dB, f, sigma, L_dB, B, T, F_dB);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SNRPlotAxes = uiaxes(app.UIFigure);
            title(app.SNRPlotAxes, 'Title')
            xlabel(app.SNRPlotAxes, 'X')
            ylabel(app.SNRPlotAxes, 'Y')
            zlabel(app.SNRPlotAxes, 'Z')
            app.KVisionRadarSNRHesaplaycLabel = uilabel(app.UIFigure);
            app.KVisionRadarSNRHesaplaycLabel.Text = 'K-Vision – Radar SNR Hesaplayıcı';
            app.PtWLabel = uilabel(app.UIFigure);
            app.PtWLabel.Text = 'Pt, (W)';
            app.PtEditField = uieditfield(app.UIFigure, 'numeric');
            app.TKEditFieldLabel = uilabel(app.UIFigure);
            app.TKEditFieldLabel.Text = 'T, (K)';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.GdBLabel = uilabel(app.UIFigure);
            app.GdBLabel.Text = 'G, (dB)';
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.fHzEditFieldLabel = uilabel(app.UIFigure);
            app.fHzEditFieldLabel.Text = 'f, (Hz)';
            app.FEditField = uieditfield(app.UIFigure, 'numeric');
            app.FdBEditFieldLabel = uilabel(app.UIFigure);
            app.FdBEditFieldLabel.Text = 'F, (dB)';
            app.FdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.LdBEditFieldLabel = uilabel(app.UIFigure);
            app.LdBEditFieldLabel.Text = 'L, dB';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'σ, (m²)';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.RmEditFieldLabel = uilabel(app.UIFigure);
            app.RmEditFieldLabel.Text = 'R, (m)';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.BHzEditFieldLabel = uilabel(app.UIFigure);
            app.BHzEditFieldLabel.Text = 'B, (Hz)';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.HesaplaButton = uibutton(app.UIFigure, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'SNR Hesapla';
            app.SNRLabel = uilabel(app.UIFigure);
            app.SNRLabel.Text = 'SNR: ___ dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = K_Vision_System

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end