classdef RadarRangeResolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DeerGirinEditFieldInput  matlab.ui.control.NumericEditField
        DeerGirinLabel           matlab.ui.control.Label
        ResultLabel              matlab.ui.control.Label
        HesaplamaTrDropDown      matlab.ui.control.DropDown
        HesaplamaTrLabel         matlab.ui.control.Label
        HesaplaButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            % 1. Dropdown'dan hesaplama türünü al
    hesaplamaTuru = app.HesaplamaTrDropDown.Value;
    
    % 2. Giriş değerini al
    girilenDeger = str2double(app.DeerGirinEditFieldInput.Value);
    
    % 3. Fonksiyon parametresini belirle
    if contains(hesaplamaTuru, 'Pulse Width')
        param = 'tau';
    else
        param = 'bandwidth';
    end
    
    % 4. Hesaplama fonksiyonunu çağır
    try
        [deltaR, ~] = range_resolution(app, param, girilenDeger);
        % 5. Sonucu label'a yaz
        app.ResultLabel.Text = sprintf('ΔR: %.3f m', deltaR);
    catch hata
        app.ResultLabel.Text = "Hatalı giriş!";
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Radar Range Resolated')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HesaplaButton = uibutton(app.UIFigure, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.HesaplamaTrLabel = uilabel(app.UIFigure);
            app.HesaplamaTrLabel.Text = 'Hesaplama Türü:';
            app.HesaplamaTrDropDown = uidropdown(app.UIFigure);
            app.HesaplamaTrDropDown.Items = {'Pulse Width (τ)', 'Band Width (B)'};
            app.HesaplamaTrDropDown.Value = 'Pulse Width (τ)';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'ΔR:';
            app.DeerGirinLabel = uilabel(app.UIFigure);
            app.DeerGirinLabel.Text = 'Değer Girin:';
            app.DeerGirinEditFieldInput = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarRangeResolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end